classdef flyflyDataMerger_OL_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                      matlab.ui.Figure
        updatePlot                   matlab.ui.control.CheckBox
        loadTrigger                  matlab.ui.control.CheckBox
        uipanel3                     matlab.ui.container.Panel
        matchedParameter             matlab.ui.control.EditField
        customTag                    matlab.ui.control.EditField
        text8                        matlab.ui.control.Label
        text5                        matlab.ui.control.Label
        uipanel2                     matlab.ui.container.Panel
        uipanel5                     matlab.ui.container.Panel
        animalNo                     matlab.ui.control.EditField
        new_animal_button            matlab.ui.control.Button
        positionNo                   matlab.ui.control.EditField
        new_position_button          matlab.ui.control.Button
        uipanel4                     matlab.ui.container.Panel
        cellNo                       matlab.ui.control.EditField
        newCell                      matlab.ui.control.Button
        trash                        matlab.ui.control.Button
        next                         matlab.ui.control.Button
        previous                     matlab.ui.control.Button
        uipanel1                     matlab.ui.container.Panel
        intra_or_extra_button_group  matlab.ui.container.ButtonGroup
        Extracellular                matlab.ui.control.RadioButton
        Intracellular                matlab.ui.control.RadioButton
        text10                       matlab.ui.control.Label
        shiftTime                    matlab.ui.control.EditField
        text7                        matlab.ui.control.Label
        tolerance                    matlab.ui.control.EditField
        setExportFolder              matlab.ui.control.Button
        matchData                    matlab.ui.control.Button
        mergeFiles                   matlab.ui.control.Button
        pathNameParameters           matlab.ui.control.Label
        pathNameData                 matlab.ui.control.Label
        setPathNameParametersWrap    matlab.ui.control.Button
        setPathNameDataWrap          matlab.ui.control.Button
        text4                        matlab.ui.control.Label
        listbox1                     matlab.ui.control.ListBox
        axes2                        matlab.ui.control.UIAxes
        axes1                        matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function cellTag_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to cellTag (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function n = coolSpacer(app, n)
            
            if n >= 10000
                n = 1;
            else
                n = 2 + coolSpacer(app, n*10);
            end
        end
        
        function listbox2_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to listbox2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function updated = setPathNameData(~, handles)
            localData = getappdata(gcf, 'localData');
            
            [fileName, pathName]    = uigetfile('Choose file with exported MatLab data', '*.mat');
            
            if fileName == 0
                updated = 0;
                return
            end
            
                localData.pathNameData = [pathName fileName];
            
                startTimesData           = tickTimes2timeString([pathName fileName]);
                localData.startTimesData = startTimesData;
                N                        = length(localData.startTimesData);
            
                localData.dataBlock      = getDataBlock(localData.pathNameData, 0001, 2);   %REVERSED
            
                if get(handles.loadTrigger, 'Value')
                    localData.triggerBlock = getDataBlock(localData.pathNameData, 0001, 1); %REVERSED
                end
            
                %array with indice of blocks. if a block is removed from blockList the
                %corresponding entry in this array will also be removed which makes it
                %possible to keep track of which files is still active.
                localData.chosenBlock    = 1:N;
                localData.cellList       = ones(1, N);
                localData.animalList       = ones(1, N);
                localData.positionList       = ones(1, N);
                localData.tagList        = cell(1, N);
            
                setappdata(gcf, 'localData', localData);
                updated = 1;
        end
        
        function updated = setPathNameParameters(app)
            %aquires pathname of folder with parameter data
            
            localData = getappdata(gcf, 'localData');
            
            pathName = uigetdir('Choose folder with parameter files corresponding to chosen data file');
            
            if pathName %pathname == 0 if press on cancel
                localData.pathNameParameters  = [pathName '/'];
            
                date = getRecordingDate(localData.pathNameData);
                disp('THE DATA RECORDING DATE IS:');
                disp(date);
                [startTimesParameters, fileNameList, trialList] = paramFile2timeString(localData.pathNameParameters, date);
            
                localData.startTimesParameters = startTimesParameters;
                localData.fileNameList         = fileNameList;
                localData.trialList            = trialList;
            
                setappdata(gcf, 'localData', localData);
                updated = 1;
                load chirp; sound(y,Fs);
            else
                updated = 0;
            end
        end
        
        function updateFigure(app, handles)
            %updates all objects in figure
            
            localData = getappdata(gcf, 'localData');
            
            listboxIndex = get(handles.listbox1, 'Value');
            index        = localData.chosenBlock(listboxIndex);
            
            %chosen datafile and folder
            set(handles.pathNameData, 'String', localData.pathNameData);
            set(handles.pathNameParameters, 'String', localData.pathNameParameters);
            
            %list of blocks (listbox1)
            if localData.startTimesData %false if no data loaded
                for n = 1:length(localData.startTimesData);
                    tmp = datestr(localData.startTimesData(n));
                    tmp = tmp(end-8:end); %pick out time from datestring
            
                    P = coolSpacer(app, localData.chosenBlock(n));
                    spacing = '';
                    for p = 1:P spacing = [spacing ' ']; end
                    if localData.intracellular
                        localData.blockList{n} = ['Block ' num2str(localData.chosenBlock(n)) ':' spacing tmp '   Cell: ' num2str(localData.cellList(n))];
                    else
                        localData.blockList{n} = ['Block ' num2str(localData.chosenBlock(n)) ':' spacing tmp '   Animal: ' num2str(localData.animalList(n)) '   Position: ' num2str(localData.positionList(n))];
                    end
                end
                set(handles.listbox1, 'String', localData.blockList);
            end
            
            %plot of chosen datablock (axes1)
            if ~isempty(localData.dataBlock)
            
                plot(handles.axes1, localData.dataBlock);
                axis(handles.axes1, 'tight');
                %xlabel(handles.axes1, 'Time');
                %ylabel(handles.axes1, 'Voltage [V]');
            end
            
            %plot of chosen datablock trigger (axes2)
            if ~isempty(localData.triggerBlock) && get(handles.loadTrigger, 'Value')
            
                plot(handles.axes2, localData.triggerBlock);
                axis(handles.axes2, [0 length(localData.triggerBlock) -0.2*max(localData.triggerBlock) 1.2*max(localData.triggerBlock)]);
                %xlabel(handles.axes1, 'Time');
                %ylabel(handles.axes1, 'Voltage [V]');
            end
            
            %enable/disable match button
            if isempty(localData.pathNameParameters) || isempty(localData.pathNameData)
                set(handles.matchData, 'Enable', 'off');
            else
                set(handles.matchData, 'Enable', 'on');
            end
            
            %enable/disable merge button
            if isempty(localData.pathNameParameters) || isempty(localData.pathNameData) || isempty(localData.indiceList)
                set(handles.mergeFiles, 'Enable', 'off');
            else
                set(handles.mergeFiles, 'Enable', 'on');
            
                parIndex = localData.indiceList(index);
            
                if parIndex
                    matchedString = [num2str(localData.fileNameList{parIndex}), ' , trial ' num2str(localData.trialList{parIndex}) ];
                else %no match
                    matchedString = 'No match found for this block';
                end
            
                set(handles.matchedParameter, 'String', matchedString);
            end
            
            %display tag
            set(handles.customTag, 'String', '');
            if ~isempty(localData.tagList)
                if ~isempty(localData.tagList{listboxIndex})
                    set(handles.customTag, 'String', localData.tagList{listboxIndex});
                end
            end
            
            setappdata(gcf, 'localData', localData);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function flyflyDataMerger_OpeningFcn(app, varargin)
            % --- Executes just before flyflyDataMerger is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to flyflyDataMerger (see VARARGIN)
            
            % Choose default command line output for flyflyDataMerger
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % UIWAIT makes flyflyDataMerger wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
            
            
            %
            %
            addpath('subfunctions');
            
            %init data
            localData.pathNameParameters  = ''; %path name to parameter folder
            localData.pathNameData        = ''; %path name to data file
            
            localData.blockList           = {}; %cell array with names for listbox
            localData.dataBlock           = []; %current loaded data block for plot
            localData.triggerBlock        = []; %current loaded trigger block for plot
            localData.chosenBlock         = 1;  %array with numbers of block in listbox.
            
            localData.startTimesData      = 0;  %array with starting times of all data blocks
            
            localData.startTimesParmeters = 0;  %array with starting times of all parameter trials
            localData.fileNameList        = {}; %filenames corresponding to start times
            localData.trialList           = {}; %trials counted as single block (no pauses)
            
            localData.indiceList          = []; %list of indice for matched parameter files
            localData.errorList           = []; %difference between starting times
            
            localData.cellList            = []; %cell tag added to block
            localData.positionList            = []; %cell tag added to block
            localData.animalList            = []; %cell tag added to block
            localData.tagList             = {}; %custom tag added to block
            
            localData.exportPathName      = 'exported/'; %path name to export merged files to
            
            localData.intracellular         = true; % intra vs extra determines options for adding cells/animals/positions
            
            %turn off axis
            axis(handles.axes1, 'off');
            axis(handles.axes2, 'off');
            
            %save data
            setappdata(gcf, 'localData', localData);
            
            updateFigure(app, handles);
        end

        % Value changed function: customTag
        function customTag_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to customTag (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of customTag as text
            %        str2double(get(hObject,'String')) returns contents of customTag as a double
            
            localData = getappdata(gcf, 'localData');
            
            if ~isempty(localData.blockList)
                index = get(handles.listbox1, 'Value');
                localData.tagList{index} = get(handles.customTag, 'String');
            
                setappdata(gcf, 'localData', localData);
                disp(['Tag added to block ' num2str(localData.chosenBlock(index))]);
            end
        end

        % Selection changed function: intra_or_extra_button_group
        function intra_or_extra_button_group_SelectionChangedFcn(app, event)
            % --- Executes when selected object is changed in intra_or_extra_button_group.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to the selected object in intra_or_extra_button_group
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            localData = getappdata(gcf, 'localData');
            i_or_e = get(eventdata.NewValue,'Tag');
            if strcmp(i_or_e, 'Intracellular')
                localData.intracellular = true;
            else
                localData.intracellular = false;
            end
            setappdata(gcf, 'localData', localData);
        end

        % Value changed function: listbox1
        function listbox1_Callback(app, event)
            % --- Executes on selection change in listbox1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox1 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox1
            
            localData = getappdata(gcf, 'localData');
            
            if get(handles.updatePlot, 'Value') && ~isempty(localData.dataBlock)
            
            
                index = get(hObject,'Value');
                localData.dataBlock    = getDataBlock(localData.pathNameData, localData.chosenBlock(index), 2); %reversed
            
                if get(handles.loadTrigger, 'Value')
                    localData.triggerBlock = getDataBlock(localData.pathNameData, localData.chosenBlock(index), 1); %reversed
                end
            
                setappdata(gcf, 'localData', localData);
            end
            updateFigure(app, handles);
        end

        % Button pushed function: matchData
        function matchData_Callback(app, event)
            % --- Executes on button press in matchData.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to matchData (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            localData = getappdata(gcf, 'localData');
            
            startTimesData       = localData.startTimesData;
            startTimesParameters = localData.startTimesParameters;
            
            %find indice of parameter files corresponding to data block
            
            shift = str2num(get(handles.shiftTime,'String'))/(60*60*24); %Frank edit
            
            [indice, error] = corrDataParam(startTimesData, startTimesParameters,shift);
            
            tolerance      = str2num(get(handles.tolerance, 'String'));
            
            missed = abs(error)> tolerance;
            K = sum(missed);
            if K>0
                missedIndex = find(missed);
            
                disp('  --Match Report--');
                disp('No matches found for the following blocks: ');
                for k = 1:K
                    blockNo = missedIndex(k);
                    %disp(['Block ' num2str(blockNo) ', starting at ' datestr(localData.startTimesData(blockNo)) ': Closest time is ' datestr(localData.startTimesParameters) localData.fileNameList{indice(blockNo)} ' (error: ' num2str(error(missedIndex(k))) 's)']);
                    disp(['Block ' num2str(blockNo) ', starting at ' datestr(localData.startTimesData(blockNo)) ': Error: ' num2str(error(missedIndex(k))) 's']);
                    indice(missedIndex(k)) = 0;
                end
                disp(['  --Match Report Finished: ' num2str(K) ' unmatched blocks']);
            else
                disp(['Matches found for all blocks. Tolerance of ' num2str(tolerance) 's used.']);
            end
            
            localData.indiceList = indice;
            localData.errorList  = error;
            
            setappdata(gcf, 'localData', localData);
            updateFigure(app, handles);
            
            figure;
            subplot(3, 1, 1);
            plot(sort(localData.startTimesParameters)); hold on;
            plot(localData.startTimesData, 'r');
            %{
            bb = max(localData.startTimesParameters)-min(localData.startTimesParameters)
            rr=localData.startTimesData(end)-localData.startTimesData(1)
            ratio = bb/rr
            %}
            title('Raw data, Parameters(blue), Data(red)');
            
            subplot(3, 1, 2);
            plot(localData.startTimesParameters(localData.indiceList(localData.indiceList>0))); hold on;
            plot(localData.startTimesData(localData.indiceList>0), 'r');
            title('Fitted data, Parameters(blue), Data(red)');
            
            subplot(3, 1, 3);
            plot(localData.errorList)
            title('Error including excluded blocks.');
        end

        % Button pushed function: mergeFiles
        function mergeFiles_Callback(app, event)
            % --- Executes on button press in mergeFiles.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to mergeFiles (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            localData = getappdata(gcf, 'localData');
            load(localData.pathNameData); %load file with data_blocks
            
            disp('--Merging parameters with data blocks-- ');
            
            for n = 1:length(localData.indiceList)
            
            
                index = localData.indiceList(n); %index of parameterfile corresponding to current block
                blockIndex = localData.chosenBlock(n);
            
                if index ~= 0 %match exist
                    parameterFile = [localData.pathNameParameters localData.fileNameList{index}];
                    load(parameterFile);
            
                    %dataBlock  = getDataBlock(localData.pathNameData, n, 1);
            
                    % sampsamp convention
            %         dataBlock = eval(['data_block' num2str(n) '(2,:)']); %REVERSED
                    %%It was like above, but to save all values in data_block, Changed to
                    dataBlock = eval(['data_block' num2str(n) '(1:end,:)']); %REVERSED
            %         dataBlock = dataBlock(~isnan(dataBlock)); %remove NaN % Do not do
            %         this
            
                    unitBlock = [];
                    if exist('unit_block1', 'var')
                        unitBlock = eval(['unit_block' num2str(n)]);
                    end
            
                    %frankfrank convention
                    %dataBlock = eval(['data_block' num2str(n,'%04.0f') '(2,:)']);


                    trial      = localData.trialList{index};
            
                    parameterFileName = localData.fileNameList{index}; %name of parameter file excluding path
            
                    experimentName = parameterFileName(1:end-25); %remove .mat in end of filename
                    dateTime = parameterFileName(end-23:end-4);
            
                    blockNumber = num2str(blockIndex);
                    if length(blockNumber) == 3
                        blockNumber = ['0' blockNumber];
                    elseif length(blockNumber) == 2
                        blockNumber = ['00' blockNumber];
                    elseif length(blockNumber) == 1
                        blockNumber = ['000' blockNumber];
                    end
                    blockNumber = ['Block' blockNumber];
            
                    if length(trial) == 1
                        trialNumber = num2str(trial);
                        %his code section adds zeros so trialNumber always has the same
                        %length (up to 9999 trials).
            
                        %             if length(trialNumber) == 3
                        %                 trialNumber = ['0' trialNumber];
                        %             elseif length(trialNumber) == 2
                        %                 trialNumber = ['00' trialNumber];
                        %             elseif length(trialNumber) == 1
                        %                 trialNumber = ['000' trialNumber];
                        %             end
                    else
                        trialNumber = [num2str(trial(1)) '-' num2str(trial(end))];
                    end
            
                    path = localData.exportPathName;
            
                    tag = localData.tagList{n};
            
                    exportName = experimentName;
            
                    if localData.intracellular
                        cellNumber = num2str(localData.cellList(n));
                        if length(cellNumber) == 1
                            cellNumber = ['0' cellNumber];
                        end
                        exportName = [exportName '-Cell'  cellNumber];
                    else
                        animalNumber = num2str(localData.animalList(n));
                        if length(animalNumber) == 1
                            animalNumber = ['0' animalNumber];
                        end
                        positionNumber = num2str(localData.positionList(n));
                        if length(positionNumber) == 1
                            positionNumber = ['0' positionNumber];
                        end
                        exportName = [exportName '-N'  animalNumber '-P'  positionNumber];
                    end
            
                    exportName = [exportName '-Trial' trialNumber  ];
            
                    % ---------------------------------------------------
            
                    savedName = saveStimulus(path, exportName, dataBlock, unitBlock, parameterFile, trial, tag);
            
                    disp(['Parameters saved to ' savedName]);
                end
            
            end
            disp('--Parameter merging finished-- ');
            load chirp; sound(y,Fs);
            updateFigure(app, handles);
        end

        % Button pushed function: newCell
        function newCell_Callback(app, event)
            % --- Executes on button press in newCell.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to newCell (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            localData = getappdata(gcf, 'localData');
            
            index     = get(handles.listbox1, 'Value'); %chosen block
            
            %change cellList:
            cell = str2num(get(handles.cellNo, 'String'));
            localData.cellList(index:end) = cell;
            
            set(handles.cellNo, 'String', num2str(cell+1));
            
            setappdata(gcf, 'localData', localData);
            updateFigure(app, handles);
        end

        % Button pushed function: new_animal_button
        function new_animal_button_Callback(app, event)
            % --- Executes on button press in new_animal_button.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to new_animal_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            localData = getappdata(gcf, 'localData');
            
            index     = get(handles.listbox1, 'Value'); %chosen block
            
            %change cellList:
            animal = str2num(get(handles.animalNo, 'String'));
            localData.positionList(index:end) = 1;
            localData.animalList(index:end) = animal;
            
            set(handles.positionNo, 'String', '2');
            set(handles.animalNo, 'String', num2str(animal+1));
            
            setappdata(gcf, 'localData', localData);
            updateFigure(app, handles)
        end

        % Button pushed function: new_position_button
        function new_position_button_Callback(app, event)
            % --- Executes on button press in new_position_button.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to new_position_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            localData = getappdata(gcf, 'localData');
            
            index     = get(handles.listbox1, 'Value'); %chosen block
            
            %change cellList:
            position = str2num(get(handles.positionNo, 'String'));
            localData.positionList(index:end) = position;
            
            set(handles.positionNo, 'String', num2str(position+1));
            
            setappdata(gcf, 'localData', localData);
            updateFigure(app, handles)
        end

        % Button pushed function: next
        function next_Callback(app, event)
            % --- Executes on button press in next.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to next (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %next: displays the next block in the file.
            %button should be grayed out if no file is loaded yet
            
            localData = getappdata(gcf, 'localData');
            newIndex = get(handles.listbox1, 'Value') ;
            
            if newIndex < length(localData.startTimesData)
                newIndex = newIndex +1;
                localData.dataBlock    = getDataBlock(localData.pathNameData, localData.chosenBlock(newIndex), 2); %Reversed
            
                if get(handles.loadTrigger, 'Value')
                    localData.triggerBlock = getDataBlock(localData.pathNameData, localData.chosenBlock(newIndex), 1); %reversed
                end
            end
            
            set(handles.listbox1, 'Value', newIndex);
            
            setappdata(gcf, 'localData', localData);
            updateFigure(app, handles);
        end

        % Button pushed function: previous
        function previous_Callback(app, event)
            % --- Executes on button press in previous.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to previous (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            localData = getappdata(gcf, 'localData');
            newIndex = get(handles.listbox1, 'Value') ;
            
            if newIndex > 1
                newIndex = newIndex -1;
                localData.dataBlock    = getDataBlock(localData.pathNameData, localData.chosenBlock(newIndex), 2); %erversed
            
                if get(handles.loadTrigger, 'Value')
                    localData.triggerBlock = getDataBlock(localData.pathNameData, localData.chosenBlock(newIndex), 1); %reversed
                end
            end
            
            set(handles.listbox1, 'Value', newIndex);
            
            setappdata(gcf, 'localData', localData);
            updateFigure(app, handles);
        end

        % Button pushed function: setExportFolder
        function setExportFolder_Callback(app, event)
            % --- Executes on button press in setExportFolder.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to setExportFolder (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            localData = getappdata(gcf, 'localData');
            
            pathName = uigetdir('Choose folder to export data to');
            
            if pathName %false if press on cancel
                localData.exportPathName = [pathName '/'];
            end
            
            setappdata(gcf, 'localData', localData);
        end

        % Button pushed function: setPathNameDataWrap
        function setPathNameDataWrap_Callback(app, event)
            % --- Executes on button press in setPathNameDataWrap.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to setPathNameDataWrap (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            updated = setPathNameData(app, handles);
            
            if updated
                updateFigure(app, handles);
            end
        end

        % Button pushed function: setPathNameParametersWrap
        function setPathNameParametersWrap_Callback(app, event)
            % --- Executes on button press in setPathNameParametersWrap.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to setPathNameParametersWrap (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            updated = setPathNameParameters(app);
            if updated
                updateFigure(app, handles);
            end
        end

        % Button pushed function: trash
        function trash_Callback(app, event)
            % --- Executes on button press in trash.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to trash (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %trash: removes the selected block from the list of block to be correlated with parameter files
            %button should be grayed out if no file is loaded yet
            
            localData = getappdata(gcf, 'localData');
            index = get(handles.listbox1, 'Value') ; %selected block
            
            if get(handles.updatePlot, 'Value')
                localData.dataBlock    = getDataBlock(localData.pathNameData, localData.chosenBlock(index), 2); %reversed
            
                if get(handles.loadTrigger, 'Value')
                    localData.triggerBlock = getDataBlock(localData.pathNameData, localData.chosenBlock(index), 1); %reversed
                end
            end
            
            if index == length(localData.chosenBlock)
                set(handles.listbox1, 'Value', index-1);
            end
            
            %remove data
            localData.startTimesData = localData.startTimesData([1:index-1 index+1:end]);
            localData.blockList      = localData.blockList([1:index-1 index+1:end]);
            localData.chosenBlock    = localData.chosenBlock([1:index-1 index+1:end]);
            
            localData.tagList        = localData.tagList([1:index-1 index+1:end]);
            localData.cellList       = localData.cellList([1:index-1 index+1:end]);
            
            if ~isempty(localData.indiceList)  %if correlation is already made
                localData.indiceList = localData.indiceList([1:index-1 index+1:end]);
                localData.errorList  = localData.errorList ([1:index-1 index+1:end]);
            end
            
            setappdata(gcf, 'localData', localData);
            updateFigure(app, handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'flyflyDataMerger';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            colormap(app.axes1, 'parula')
            app.axes2 = uiaxes(app.figure1);
            app.axes2.Tag = 'axes2';
            colormap(app.axes2, 'parula')
            app.listbox1 = uilistbox(app.figure1);
            app.listbox1.Items = {};
            app.listbox1.ValueChangedFcn = createCallbackFcn(app, @listbox1_Callback, true);
            app.listbox1.Tag = 'listbox1';
            app.listbox1.Value = {};
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Data Blocks';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Tag = 'uipanel1';
            app.setPathNameDataWrap = uibutton(app.uipanel1, 'push');
            app.setPathNameDataWrap.ButtonPushedFcn = createCallbackFcn(app, @setPathNameDataWrap_Callback, true);
            app.setPathNameDataWrap.Tag = 'setPathNameDataWrap';
            app.setPathNameDataWrap.Text = 'Labchart File';
            app.setPathNameParametersWrap = uibutton(app.uipanel1, 'push');
            app.setPathNameParametersWrap.ButtonPushedFcn = createCallbackFcn(app, @setPathNameParametersWrap_Callback, true);
            app.setPathNameParametersWrap.Tag = 'setPathNameParametersWrap';
            app.setPathNameParametersWrap.Text = 'Parameter Folder';
            app.pathNameData = uilabel(app.uipanel1);
            app.pathNameData.Tag = 'pathNameData';
            app.pathNameData.Text = 'Static Text';
            app.pathNameParameters = uilabel(app.uipanel1);
            app.pathNameParameters.Tag = 'pathNameParameters';
            app.pathNameParameters.Text = 'Static Text';
            app.mergeFiles = uibutton(app.uipanel1, 'push');
            app.mergeFiles.ButtonPushedFcn = createCallbackFcn(app, @mergeFiles_Callback, true);
            app.mergeFiles.Tag = 'mergeFiles';
            app.mergeFiles.Text = 'Merge Files';
            app.matchData = uibutton(app.uipanel1, 'push');
            app.matchData.ButtonPushedFcn = createCallbackFcn(app, @matchData_Callback, true);
            app.matchData.Tag = 'matchData';
            app.matchData.Text = 'Match Data';
            app.setExportFolder = uibutton(app.uipanel1, 'push');
            app.setExportFolder.ButtonPushedFcn = createCallbackFcn(app, @setExportFolder_Callback, true);
            app.setExportFolder.Tag = 'setExportFolder';
            app.setExportFolder.Text = 'Export Folder';
            app.tolerance = uieditfield(app.uipanel1, 'text');
            app.tolerance.Tag = 'tolerance';
            app.tolerance.Value = '1';
            app.text7 = uilabel(app.uipanel1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Tolerance [s]';
            app.shiftTime = uieditfield(app.uipanel1, 'text');
            app.shiftTime.Tag = 'shiftTime';
            app.shiftTime.Value = '3';
            app.text10 = uilabel(app.uipanel1);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Shift Parameter [s]';
            app.intra_or_extra_button_group = uibuttongroup(app.uipanel1);
            app.intra_or_extra_button_group.SelectionChangedFcn = createCallbackFcn(app, @intra_or_extra_button_group_SelectionChangedFcn, true);
            app.intra_or_extra_button_group.Title = 'Button Group';
            app.intra_or_extra_button_group.Tag = 'intra_or_extra_button_group';
            app.Intracellular = uiradiobutton(app.intra_or_extra_button_group);
            app.Intracellular.Tag = 'Intracellular';
            app.Intracellular.Text = 'Intracellular';
            app.Intracellular.Value = true;
            app.Extracellular = uiradiobutton(app.intra_or_extra_button_group);
            app.Extracellular.Tag = 'Extracellular';
            app.Extracellular.Text = 'Extracellular';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Tag = 'uipanel2';
            app.previous = uibutton(app.uipanel2, 'push');
            app.previous.ButtonPushedFcn = createCallbackFcn(app, @previous_Callback, true);
            app.previous.Tag = 'previous';
            app.previous.Text = '<<';
            app.next = uibutton(app.uipanel2, 'push');
            app.next.ButtonPushedFcn = createCallbackFcn(app, @next_Callback, true);
            app.next.Tag = 'next';
            app.next.Text = '>>';
            app.trash = uibutton(app.uipanel2, 'push');
            app.trash.ButtonPushedFcn = createCallbackFcn(app, @trash_Callback, true);
            app.trash.Tag = 'trash';
            app.trash.Text = 'Trash';
            app.uipanel4 = uipanel(app.uipanel2);
            app.uipanel4.Title = 'Intracellular';
            app.uipanel4.Tag = 'uipanel4';
            app.newCell = uibutton(app.uipanel4, 'push');
            app.newCell.ButtonPushedFcn = createCallbackFcn(app, @newCell_Callback, true);
            app.newCell.Tag = 'newCell';
            app.newCell.Text = 'New Cell';
            app.cellNo = uieditfield(app.uipanel4, 'text');
            app.cellNo.Tag = 'cellNo';
            app.cellNo.Value = '2';
            app.uipanel5 = uipanel(app.uipanel2);
            app.uipanel5.Title = 'Extracellular';
            app.uipanel5.Tag = 'uipanel5';
            app.new_position_button = uibutton(app.uipanel5, 'push');
            app.new_position_button.ButtonPushedFcn = createCallbackFcn(app, @new_position_button_Callback, true);
            app.new_position_button.Tag = 'new_position_button';
            app.new_position_button.Text = 'New Position';
            app.positionNo = uieditfield(app.uipanel5, 'text');
            app.positionNo.Tag = 'positionNo';
            app.positionNo.Value = '2';
            app.new_animal_button = uibutton(app.uipanel5, 'push');
            app.new_animal_button.ButtonPushedFcn = createCallbackFcn(app, @new_animal_button_Callback, true);
            app.new_animal_button.Tag = 'new_animal_button';
            app.new_animal_button.Text = 'New Animal';
            app.animalNo = uieditfield(app.uipanel5, 'text');
            app.animalNo.Tag = 'animalNo';
            app.animalNo.Value = '2';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Tag = 'uipanel3';
            app.text5 = uilabel(app.uipanel3);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Matched Parameter';
            app.text8 = uilabel(app.uipanel3);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Custom Tag';
            app.customTag = uieditfield(app.uipanel3, 'text');
            app.customTag.ValueChangedFcn = createCallbackFcn(app, @customTag_Callback, true);
            app.customTag.Tag = 'customTag';
            app.matchedParameter = uieditfield(app.uipanel3, 'text');
            app.matchedParameter.Tag = 'matchedParameter';
            app.matchedParameter.Value = ' (Press "Match Data")';
            app.loadTrigger = uicheckbox(app.figure1);
            app.loadTrigger.Tag = 'loadTrigger';
            app.loadTrigger.Text = 'Load Trigger';
            app.loadTrigger.Value = true;
            app.updatePlot = uicheckbox(app.figure1);
            app.updatePlot.Tag = 'updatePlot';
            app.updatePlot.Text = 'Plot on Mark';
            app.updatePlot.Value = true;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = flyflyDataMerger_OL_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)flyflyDataMerger_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end