classdef flyflyDataMerger < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                      matlab.ui.Figure
        updatePlot                   matlab.ui.control.CheckBox
        loadTrigger                  matlab.ui.control.CheckBox
        uipanel3                     matlab.ui.container.Panel
        matchedParameter             matlab.ui.control.EditField
        customTag                    matlab.ui.control.EditField
        text8                        matlab.ui.control.Label
        text5                        matlab.ui.control.Label
        uipanel2                     matlab.ui.container.Panel
        animalNo                     matlab.ui.control.EditField
        new_animal_button            matlab.ui.control.Button
        uipanel5                     matlab.ui.container.Panel
        positionNo                   matlab.ui.control.EditField
        new_position_button          matlab.ui.control.Button
        uipanel4                     matlab.ui.container.Panel
        cellNo                       matlab.ui.control.EditField
        newCell                      matlab.ui.control.Button
        trash                        matlab.ui.control.Button
        next                         matlab.ui.control.Button
        previous                     matlab.ui.control.Button
        uipanel1                     matlab.ui.container.Panel
        simplifyOutput               matlab.ui.control.CheckBox
        intra_or_extra_button_group  matlab.ui.container.ButtonGroup
        Extracellular                matlab.ui.control.RadioButton
        Intracellular                matlab.ui.control.RadioButton
        text10                       matlab.ui.control.Label
        shiftTime                    matlab.ui.control.EditField
        text7                        matlab.ui.control.Label
        tolerance                    matlab.ui.control.EditField
        setExportFolder              matlab.ui.control.Button
        matchData                    matlab.ui.control.Button
        mergeFiles                   matlab.ui.control.Button
        pathNameParameters           matlab.ui.control.Label
        pathNameData                 matlab.ui.control.Label
        setPathNameParametersWrap    matlab.ui.control.Button
        setPathNameDataWrap          matlab.ui.control.Button
        text4                        matlab.ui.control.Label
        dataBlockList                matlab.ui.control.ListBox
        axes2                        matlab.ui.control.UIAxes
        axes1                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

    end
    methods (Access = private)
        function updated = setPathNameData(app)
            localData = getappdata(0, 'localData');
            
            [fileName, pathName]    = uigetfile('Choose file with exported MatLab data', '*.mat');
            
            if fileName == 0
                updated = 0;
                return
            end
            
                localData.pathNameData = [pathName fileName];
            
                localData.startTimesData = tickTimes2timeString([pathName fileName]);
                N                        = length(localData.startTimesData);
            
                localData.dataBlock      = getDataBlock(localData.pathNameData, 1, 1);   %REVERSED
            
                if app.loadTrigger.Value
                    try
                        localData.triggerBlock = getDataBlock(localData.pathNameData, 1, 2); %REVERSED
                    catch
                        disp('Data blocks found, but no ''trigger blocks'' identified. Make sure this is correct!')
                        app.loadTrigger.Value = 0;
                    end
                end
            
                %array with indice of blocks. if a block is removed from blockList the
                %corresponding entry in this array will also be removed which makes it
                %possible to keep track of which files is still active.
                localData.chosenBlock    = 1:N;
                localData.cellList       = ones(1, N);
                localData.animalList       = ones(1, N);
                localData.positionList       = ones(1, N);
                localData.tagList        = cell(1, N);
            
                setappdata(0, 'localData', localData);
                updated = 1;
        end
        
        function updated = setPathNameParameters(app)
            %acquires pathname of folder with parameter data
            
            localData = getappdata(0, 'localData');
            
            pathname = uigetdir('Choose folder with parameter files corresponding to chosen data file');
            
            if pathname %pathname == 0 if press on cancel
                localData.pathNameParameters  = [pathname '/'];
            
                date = getRecordingDate(localData.pathNameData);
                disp('THE DATA RECORDING DATE IS:');
                disp(date);
                [startTimesParameters, fileNameList, trialList] = paramFile2timeString(localData.pathNameParameters, date);
            
                localData.startTimesParameters = startTimesParameters;
                localData.fileNameList         = fileNameList;
                localData.trialList            = trialList;
            
                setappdata(0, 'localData', localData);
                updated = 1;
                % COMMENTING THIS OUT COS ITS LOUD AS FUCK AND SCARED THE
                % SHIT OUT OF ME
                % - JAXON
                % load chirp; sound(y,Fs);
            else
                updated = 0;
            end
        end
        
        function updateFigure(app)
            % Updates all objects in figure
            localData = getappdata(0, 'localData');
            
            % Chosen datafile and folder
            app.pathNameData.Text = localData.pathNameData;
            app.pathNameParameters.Text = localData.pathNameParameters;
            
            % List of blocks (listbox1)
            if localData.startTimesData % False if no data loaded
                for n = 1:length(localData.startTimesData)
                    tmp = datestr(localData.startTimesData(n));
                    tmp = tmp(end-8:end); % Pick out time from datestring
            
                    P = coolSpacer(app, localData.chosenBlock(n));
                    spacing = '';
                    for p = 1:P
                        spacing = [spacing ' ']; 
                    end
                    if localData.intracellular
                        localData.blockList{n} = ['Block ' num2str(localData.chosenBlock(n)) ':' spacing tmp '   Animal: ' num2str(localData.animalList(n)) '   Cell: ' num2str(localData.cellList(n))];
                    else
                        localData.blockList{n} = ['Block ' num2str(localData.chosenBlock(n)) ':' spacing tmp '   Animal: ' num2str(localData.animalList(n)) '   Position: ' num2str(localData.positionList(n))];
                    end
                end
                app.dataBlockList.Items = localData.blockList;
                app.dataBlockList.ItemsData = 1:length(localData.blockList);
            end

            listboxIndex = app.dataBlockList.Value;
            % Prevent startup errors before we have an init
            if ~isempty(listboxIndex)
                index = localData.chosenBlock(listboxIndex);
            end
            % Plot of chosen datablock (axes1)
            if ~isempty(localData.dataBlock)
                plot(app.axes1, localData.dataBlock);
                axis(app.axes1, 'tight');
            end
            
            % Plot of chosen datablock trigger (axes2)
            if ~isempty(localData.triggerBlock) && app.loadTrigger.Value
                plot(app.axes2, localData.triggerBlock);
                axis(app.axes2, [0 length(localData.triggerBlock) -0.2*max(localData.triggerBlock) 1.2*max(localData.triggerBlock)]);
            end
            
            % Enable/disable match button
            if isempty(localData.pathNameParameters) || isempty(localData.pathNameData)
                app.matchData.Enable = 'off';
            else
                app.matchData.Enable = 'on';
            end
            
            % Enable/disable merge button
            if isempty(localData.pathNameParameters) || isempty(localData.pathNameData) || isempty(localData.indiceList)
                app.mergeFiles.Enable = 'off';
            else
                app.mergeFiles.Enable = 'on';
            
                parIndex = localData.indiceList(index);
            
                if parIndex
                    matchedString = [num2str(localData.fileNameList{parIndex}), ' , trial ' num2str(localData.trialList{parIndex}) ];
                else % No match
                    matchedString = 'No match found for this block';
                end
            
                app.matchedParameter.Value = matchedString;
            end
            
            %display tag BROKEN ATM FIX LATER
            %set(handles.customTag, 'String', '');
            %if ~isempty(localData.tagList)
            %    if ~isempty(localData.tagList{2})
            %        set(handles.customTag, 'String', localData.tagList{listboxIndex});
            %    end
            %end
            
            setappdata(0, 'localData', localData);
        end
        
        function n = coolSpacer(app, n)
            
            if n >= 10000
                n = 1;
            else
                n = 2 + coolSpacer(app, n*10);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function flyflyDataMerger_OpeningFcn(app, varargin)
            % --- Executes just before flyflyDataMerger is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            % Get the path to the gui, and use that to find the subfunctions folder
            guiPath = which("flyflyDataMerger");
            guiPath = strsplit(guiPath, 'flyflyDataMerger');
            guiPath = guiPath{1};
            addpath([guiPath 'subfunctions']);
            
            %init data
            localData.pathNameParameters  = ''; %path name to parameter folder
            localData.pathNameData        = ''; %path name to data file
            
            localData.blockList           = {}; %cell array with names for listbox
            localData.dataBlock           = []; %current loaded data block for plot
            localData.triggerBlock        = []; %current loaded trigger block for plot
            localData.chosenBlock         = 1;  %array with numbers of block in listbox.
            
            localData.startTimesData      = 0;  %array with starting times of all data blocks
            
            localData.startTimesParmeters = 0;  %array with starting times of all parameter trials
            localData.fileNameList        = {}; %filenames corresponding to start times
            localData.trialList           = {}; %trials counted as single block (no pauses)
            
            localData.indiceList          = []; %list of indice for matched parameter files
            localData.errorList           = []; %difference between starting times
            
            localData.cellList            = []; %cell tag added to block
            localData.positionList            = []; %cell tag added to block
            localData.animalList            = []; %cell tag added to block
            localData.tagList             = {}; %custom tag added to block
            
            localData.exportPathName      = 'exported/'; %path name to export merged files to
            
            localData.intracellular         = true; % intra vs extra determines options for adding cells/animals/positions
            
            %turn off axis
            axis(app.axes1, 'off');
            axis(app.axes2, 'off');
            
            %save data
            setappdata(0, 'localData', localData);
            
            updateFigure(app);
        end

        % Value changed function: customTag
        function customTag_Callback(app, event)
            localData = getappdata(0, 'localData');
            
            if ~isempty(localData.blockList)
                index = app.dataBlockList.Value;
                localData.tagList{index} = app.handles.customTag.Value;
            
                setappdata(0, 'localData', localData);
                disp(['Tag added to block ' num2str(localData.chosenBlock(index))]);
            end
        end

        % Selection changed function: intra_or_extra_button_group
        function intra_or_extra_button_group_SelectionChangedFcn(app, event)
            % --- Executes when selected object is changed in intra_or_extra_button_group.
            localData = getappdata(0, 'localData');
            i_or_e = app.intra_or_extra_button_group.SelectedObject.Text;
            if strcmp(i_or_e, 'Intracellular')
                localData.intracellular = true;
            else
                localData.intracellular = false;
            end
            setappdata(0, 'localData', localData);
        end

        % Value changed function: dataBlockList
        function dataBlockList_Callback(app, event)
            % Load all app data
            localData = getappdata(0, 'localData');
            % This works only if we have loaded experiment data!
            if app.updatePlot.Value && ~isempty(localData.dataBlock)
                % Get the index of what the user selected
                index = app.dataBlockList.Value;
                localData.dataBlock = getDataBlock(localData.pathNameData, localData.chosenBlock(index), 1);
                if app.loadTrigger.Value
                    localData.triggerBlock = getDataBlock(localData.pathNameData, localData.chosenBlock(index), 2); %reversed
                end
            
                setappdata(0, 'localData', localData);
            end
            updateFigure(app);
        end

        % Button pushed function: matchData
        function matchData_Callback(app, event)
            localData = getappdata(0, 'localData');
            
            startTimesData       = localData.startTimesData;
            startTimesParameters = localData.startTimesParameters;
            
            %find indice of parameter files corresponding to data block
            
            shift = str2double(app.shiftTime.Value)/(60*60*24); %Frank edit
            
            [indice, error] = corrDataParam(startTimesData, startTimesParameters,shift);
            
            missed = abs(error)> str2double(app.tolerance.Value);
            K = sum(missed);
            if K>0
                missedIndex = find(missed);
            
                disp('  --Match Report--');
                disp('No matches found for the following blocks: ');
                for k = 1:K
                    blockNo = missedIndex(k);
                    disp(['Block ' num2str(blockNo) ', starting at ' datestr(localData.startTimesData(blockNo)) ': Error: ' num2str(error(missedIndex(k))) 's']);
                    indice(missedIndex(k)) = 0;
                end
                disp(['  --Match Report Finished: ' num2str(K) ' unmatched blocks']);
            else
                disp(['Matches found for all blocks. Tolerance of ' num2str(app.tolerance.Value) 's used.']);
            end
            
            localData.indiceList = indice;
            localData.errorList  = error;
            
            setappdata(0, 'localData', localData);
            updateFigure(app);
            
            figure;
            subplot(3, 1, 1);
            plot(sort(localData.startTimesParameters)); hold on;
            plot(localData.startTimesData, 'r');
            title('Raw data, Parameters(blue), Data(red)');
            
            subplot(3, 1, 2);
            plot(localData.startTimesParameters(localData.indiceList(localData.indiceList>0))); hold on;
            plot(localData.startTimesData(localData.indiceList>0), 'r');
            title('Fitted data, Parameters(blue), Data(red)');
            
            subplot(3, 1, 3);
            plot(localData.errorList)
            title('Error including excluded blocks.');
            app.setExportFolder.Enable = 'on';
            app.mergeFiles.Enable = 'off';
        end

        % Button pushed function: mergeFiles
        function mergeFiles_Callback(app, event)
            localData = getappdata(0, 'localData');

            
            disp('--Merging parameters with data blocks-- ');
            
            % Get the name formatting for a dataBlock
            [~, dataName]  = getDataBlock(localData.pathNameData, 1, 1);
            noUnitMsg = 0;

            for n = 1:length(localData.indiceList)
            
            
                index = localData.indiceList(n); %index of parameterfile corresponding to current block
                blockIndex = localData.chosenBlock(n);
            
                if index ~= 0 %match exist
                    parameterFile = [localData.pathNameParameters localData.fileNameList{index}];
                    block_name = [dataName num2str(n)];
                    unit_name  = ['unit_block' num2str(n)];
                    warning off % Stops message spam, we inform users that there's no units later on
                    load(localData.pathNameData, block_name, unit_name); %load file with data_blocks
                    warning on
                    load(parameterFile);
            
                    %dataBlock  = getDataBlock(localData.pathNameData, n, 1);
            
                    dataBlock = eval([dataName num2str(n) '(1:end,:)']); %REVERSED
                    unitBlock = [];
                    if exist(['unit_block' num2str(n)], 'var')
                        unitBlock = eval(['unit_block' num2str(n)]);
                    else 
                        
                        noUnitMsg = 1;
                    end
            
                    %frankfrank convention
                    %dataBlock = eval(['data_block' num2str(n,'%04.0f') '(2,:)']);


                    trial      = localData.trialList{index};
            
                    parameterFileName = localData.fileNameList{index}; %name of parameter file excluding path
            
                    experimentName = parameterFileName(1:end-25); %remove .mat in end of filename
                    dateTime = parameterFileName(end-23:end-4);
            
                    blockNumber = num2str(blockIndex);
                    if length(blockNumber) == 3
                        blockNumber = ['0' blockNumber];
                    elseif length(blockNumber) == 2
                        blockNumber = ['00' blockNumber];
                    elseif length(blockNumber) == 1
                        blockNumber = ['000' blockNumber];
                    end
                    blockNumber = ['Block' blockNumber];
            
                    if length(trial) == 1
                        trialNumber = num2str(trial);
                        %his code section adds zeros so trialNumber always has the same
                        %length (up to 9999 trials).
            
                        %             if length(trialNumber) == 3
                        %                 trialNumber = ['0' trialNumber];
                        %             elseif length(trialNumber) == 2
                        %                 trialNumber = ['00' trialNumber];
                        %             elseif length(trialNumber) == 1
                        %                 trialNumber = ['000' trialNumber];
                        %             end
                    else
                        trialNumber = [num2str(trial(1)) '-' num2str(trial(end))];
                    end
            
                    path = localData.exportPathName;
            
                    tag = localData.tagList{n};
            
                    exportName = experimentName;

                    animalNumber = num2str(localData.animalList(n));
                    if isscalar(animalNumber)
                        animalNumber = ['0' animalNumber]; %#ok<AGROW> does not grow, is reset each loop
                    end
            
                    if localData.intracellular
                        cellNumber = num2str(localData.cellList(n));
                        if isscalar(cellNumber)
                            cellNumber = ['0' cellNumber];
                        end
                        exportName = [exportName '-N'  animalNumber '-Cell'  cellNumber];
                    else
                        
                        positionNumber = num2str(localData.positionList(n));
                        if isscalar(positionNumber)
                            positionNumber = ['0' positionNumber];
                        end
                        exportName = [exportName '-N'  animalNumber '-P'  positionNumber];
                    end
            
                    exportName = [exportName '-Trial' trialNumber  ];
            
                    % ---------------------------------------------------
            
                    savedName = saveStimulus(path, exportName, dataBlock, unitBlock, parameterFile, trial, tag, app.simplifyOutput.Value);
            
                    disp(['Parameters saved to ' savedName]);
                    clear(['unit_block' num2str(n)], [dataName num2str(n)])
                end
            
            end
            if noUnitMsg == 1 && ~localData.intracellular
                disp('Data blocks merged with parameters, but no ''units'' identified. Make sure this is correct!')
            end
            disp('--Parameter merging finished-- ');
            % load chirp; sound(y,Fs);
            updateFigure(app);
        end

        % Button pushed function: newCell
        function newCell_Callback(app, event)
            localData = getappdata(0, 'localData');
            
            index     = app.dataBlockList.Value; % Chosen block
            
            %change cellList:
            cell = str2double(app.cellNo.Value);
            localData.cellList(index:end) = cell;
            
            app.cellNo.Value = num2str(cell+1);
            
            setappdata(0, 'localData', localData);
            updateFigure(app);
        end

        % Button pushed function: new_animal_button
        function new_animal_button_Callback(app, event)
            localData = getappdata(0, 'localData');
            
            index     = app.dataBlockList.Value; %chosen block
            
            %change cellList:
            animal = str2double(app.animalNo.Value);
            localData.positionList(index:end) = 1;
            localData.animalList(index:end) = animal;
            
            app.positionNo.Value = '2';
            app.animalNo.Value = num2str(animal+1);
            
            setappdata(0, 'localData', localData);
            updateFigure(app)
        end

        % Button pushed function: new_position_button
        function new_position_button_Callback(app, event)
            localData = getappdata(0, 'localData');
            
            index     = app.dataBlockList.Value; %chosen block
            
            %change cellList:
            position = str2double(app.positionNo.Value);
            localData.positionList(index:end) = position;
            
            app.positionNo.Value = num2str(position+1);
            
            setappdata(0, 'localData', localData);
            updateFigure(app)
        end

        % Button pushed function: next
        function next_Callback(app, event)
            %next: displays the next block in the file.
            %button should be grayed out if no file is loaded yet
            
            localData = getappdata(0, 'localData');
            newIndex = app.dataBlockList.Value;
            
            if newIndex < length(localData.startTimesData)
                newIndex = newIndex +1;
                localData.dataBlock    = getDataBlock(localData.pathNameData, localData.chosenBlock(newIndex), 1); %Reversed
            
                if app.loadTrigger.Value
                    localData.triggerBlock = getDataBlock(localData.pathNameData, localData.chosenBlock(newIndex), 2); %reversed
                end
            end
            
            app.dataBlockList.Value = newIndex;
            
            setappdata(0, 'localData', localData);
            updateFigure(app);
        end

        % Button pushed function: previous
        function previous_Callback(app, event)
            localData = getappdata(0, 'localData');
            newIndex = app.dataBlockList.Value;
            
            if newIndex > 1
                newIndex = newIndex -1;
                localData.dataBlock    = getDataBlock(localData.pathNameData, localData.chosenBlock(newIndex), 1); %erversed
            
                if app.loadTrigger.Value
                    localData.triggerBlock = getDataBlock(localData.pathNameData, localData.chosenBlock(newIndex), 2); %reversed
                end
            end
            
            app.dataBlockList.Value = newIndex;
            
            setappdata(0, 'localData', localData);
            updateFigure(app);
        end

        % Button pushed function: setExportFolder
        function setExportFolder_Callback(app, event)
            localData = getappdata(0, 'localData');
            
            pathName = uigetdir('Choose folder to export data to');
            
            if pathName %false if press on cancel
                localData.exportPathName = [pathName '/'];
                app.mergeFiles.Enable = 'on';
                setappdata(0, 'localData', localData);
            end
        end

        % Button pushed function: setPathNameDataWrap
        function setPathNameDataWrap_Callback(app, event)
            updated = setPathNameData(app);
            
            if updated
                updateFigure(app);
                app.setPathNameParametersWrap.Enable = 'on';
                app.matchData.Enable = 'off';
                app.setExportFolder.Enable = 'off';
                app.mergeFiles.Enable = 'off';
                app.dataBlockList.Value = 1;
            end
        end

        % Button pushed function: setPathNameParametersWrap
        function setPathNameParametersWrap_Callback(app, event)
            updated = setPathNameParameters(app);
            if updated
                updateFigure(app);
                app.matchData.Enable = 'on';
                app.setExportFolder.Enable = 'off';
                app.mergeFiles.Enable = 'off';
            end
        end

        % Button pushed function: trash
        function trash_Callback(app, event)
            %trash: removes the selected block from the list of block to be correlated with parameter files
            %button should be grayed out if no file is loaded yet
            
            localData = getappdata(0, 'localData');
            index = app.dataBlockList.Value; % Selected block
            
            if app.updatePlot.Value
                localData.dataBlock    = getDataBlock(localData.pathNameData, localData.chosenBlock(index), 1); %reversed
            
                if app.loadTrigger.Value
                    localData.triggerBlock = getDataBlock(localData.pathNameData, localData.chosenBlock(index), 2); %reversed
                end
            end
            
            if index == length(localData.chosenBlock)
                app.dataBlockList.Value = index-1;
            end
            
            % Remove data
            localData.startTimesData = localData.startTimesData([1:index-1 index+1:end]);
            localData.blockList      = localData.blockList([1:index-1 index+1:end]);
            localData.chosenBlock    = localData.chosenBlock([1:index-1 index+1:end]);
            
            localData.tagList        = localData.tagList([1:index-1 index+1:end]);
            localData.cellList       = localData.cellList([1:index-1 index+1:end]);
            
            if ~isempty(localData.indiceList)  % If correlation is already made
                localData.indiceList = localData.indiceList([1:index-1 index+1:end]);
                localData.errorList  = localData.errorList ([1:index-1 index+1:end]);
            end
            
            setappdata(gcf, 'localData', localData);
            updateFigure(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'flyflyDataMerger';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.axes2 = uiaxes(app.figure1);
            app.axes2.Tag = 'axes2';
            app.dataBlockList = uilistbox(app.figure1);
            app.dataBlockList.Items = {};
            app.dataBlockList.ValueChangedFcn = createCallbackFcn(app, @dataBlockList_Callback, true);
            app.dataBlockList.Tag = 'listbox1';
            app.dataBlockList.Value = {};
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Data Blocks';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Tag = 'uipanel1';
            app.setPathNameDataWrap = uibutton(app.uipanel1, 'push');
            app.setPathNameDataWrap.ButtonPushedFcn = createCallbackFcn(app, @setPathNameDataWrap_Callback, true);
            app.setPathNameDataWrap.Tag = 'setPathNameDataWrap';
            app.setPathNameDataWrap.Text = 'Data File';
            app.setPathNameParametersWrap = uibutton(app.uipanel1, 'push');
            app.setPathNameParametersWrap.ButtonPushedFcn = createCallbackFcn(app, @setPathNameParametersWrap_Callback, true);
            app.setPathNameParametersWrap.Tag = 'setPathNameParametersWrap';
            app.setPathNameParametersWrap.Enable = 'off';
            app.setPathNameParametersWrap.Text = 'Parameter Folder';
            app.pathNameData = uilabel(app.uipanel1);
            app.pathNameData.Tag = 'pathNameData';
            app.pathNameData.Text = 'Static Text';
            app.pathNameParameters = uilabel(app.uipanel1);
            app.pathNameParameters.Tag = 'pathNameParameters';
            app.pathNameParameters.Text = 'Static Text';
            app.mergeFiles = uibutton(app.uipanel1, 'push');
            app.mergeFiles.ButtonPushedFcn = createCallbackFcn(app, @mergeFiles_Callback, true);
            app.mergeFiles.Tag = 'mergeFiles';
            app.mergeFiles.Enable = 'off';
            app.mergeFiles.Text = 'Merge Files';
            app.matchData = uibutton(app.uipanel1, 'push');
            app.matchData.ButtonPushedFcn = createCallbackFcn(app, @matchData_Callback, true);
            app.matchData.Tag = 'matchData';
            app.matchData.Enable = 'off';
            app.matchData.Text = 'Match Data';
            app.setExportFolder = uibutton(app.uipanel1, 'push');
            app.setExportFolder.ButtonPushedFcn = createCallbackFcn(app, @setExportFolder_Callback, true);
            app.setExportFolder.Tag = 'setExportFolder';
            app.setExportFolder.Enable = 'off';
            app.setExportFolder.Text = 'Export Folder';
            app.tolerance = uieditfield(app.uipanel1, 'text');
            app.tolerance.Tag = 'tolerance';
            app.tolerance.Value = '1';
            app.text7 = uilabel(app.uipanel1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Tolerance [s]';
            app.shiftTime = uieditfield(app.uipanel1, 'text');
            app.shiftTime.Tag = 'shiftTime';
            app.shiftTime.Value = '3';
            app.text10 = uilabel(app.uipanel1);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Shift Parameter [s]';
            app.intra_or_extra_button_group = uibuttongroup(app.uipanel1);
            app.intra_or_extra_button_group.SelectionChangedFcn = createCallbackFcn(app, @intra_or_extra_button_group_SelectionChangedFcn, true);
            app.intra_or_extra_button_group.Title = 'Type of data';
            app.intra_or_extra_button_group.Tag = 'intra_or_extra_button_group';
            app.Intracellular = uiradiobutton(app.intra_or_extra_button_group);
            app.Intracellular.Tag = 'Intracellular';
            app.Intracellular.Text = 'Intracellular';
            app.Intracellular.Value = true;
            app.Extracellular = uiradiobutton(app.intra_or_extra_button_group);
            app.Extracellular.Tag = 'Extracellular';
            app.Extracellular.Text = 'Extracellular / Behaviour';
            app.simplifyOutput = uicheckbox(app.uipanel1);
            app.simplifyOutput.Text = 'Simplify output for publication';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Data Block Manager';
            app.uipanel2.Tag = 'uipanel2';
            app.previous = uibutton(app.uipanel2, 'push');
            app.previous.ButtonPushedFcn = createCallbackFcn(app, @previous_Callback, true);
            app.previous.Tag = 'previous';
            app.previous.Text = '<<';
            app.next = uibutton(app.uipanel2, 'push');
            app.next.ButtonPushedFcn = createCallbackFcn(app, @next_Callback, true);
            app.next.Tag = 'next';
            app.next.Text = '>>';
            app.trash = uibutton(app.uipanel2, 'push');
            app.trash.ButtonPushedFcn = createCallbackFcn(app, @trash_Callback, true);
            app.trash.Tag = 'trash';
            app.trash.Text = 'Trash';
            app.uipanel4 = uipanel(app.uipanel2);
            app.uipanel4.Title = 'Intracellular';
            app.uipanel4.Tag = 'uipanel4';
            app.newCell = uibutton(app.uipanel4, 'push');
            app.newCell.ButtonPushedFcn = createCallbackFcn(app, @newCell_Callback, true);
            app.newCell.Tag = 'newCell';
            app.newCell.Text = 'New Cell';
            app.cellNo = uieditfield(app.uipanel4, 'text');
            app.cellNo.Tag = 'cellNo';
            app.cellNo.Value = '2';
            app.uipanel5 = uipanel(app.uipanel2);
            app.uipanel5.Title = 'Extracellular';
            app.uipanel5.Tag = 'uipanel5';
            app.new_position_button = uibutton(app.uipanel5, 'push');
            app.new_position_button.ButtonPushedFcn = createCallbackFcn(app, @new_position_button_Callback, true);
            app.new_position_button.Tag = 'new_position_button';
            app.new_position_button.Text = 'New Position';
            app.positionNo = uieditfield(app.uipanel5, 'text');
            app.positionNo.Tag = 'positionNo';
            app.positionNo.Value = '2';
            app.new_animal_button = uibutton(app.uipanel2, 'push');
            app.new_animal_button.ButtonPushedFcn = createCallbackFcn(app, @new_animal_button_Callback, true);
            app.new_animal_button.Tag = 'new_animal_button';
            app.new_animal_button.Text = 'New Animal';
            app.animalNo = uieditfield(app.uipanel2, 'text');
            app.animalNo.Tag = 'animalNo';
            app.animalNo.Value = '2';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Tag = 'uipanel3';
            app.text5 = uilabel(app.uipanel3);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Matched Parameter';
            app.text8 = uilabel(app.uipanel3);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Custom Tag';
            app.customTag = uieditfield(app.uipanel3, 'text');
            app.customTag.ValueChangedFcn = createCallbackFcn(app, @customTag_Callback, true);
            app.customTag.Tag = 'customTag';
            app.matchedParameter = uieditfield(app.uipanel3, 'text');
            app.matchedParameter.Tag = 'matchedParameter';
            app.matchedParameter.Value = ' (Press "Match Data")';
            app.loadTrigger = uicheckbox(app.figure1);
            app.loadTrigger.Tag = 'loadTrigger';
            app.loadTrigger.Text = 'Load Trigger';
            app.loadTrigger.Value = true;
            app.updatePlot = uicheckbox(app.figure1);
            app.updatePlot.Tag = 'updatePlot';
            app.updatePlot.Text = 'Plot on Mark';
            app.updatePlot.Value = true;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = flyflyDataMerger(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)flyflyDataMerger_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end